classdef TRES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ftmSwitch           matlab.ui.control.Switch
        ValueEditField      matlab.ui.control.EditField
        TRESLabel           matlab.ui.control.Label
        IndiciesPanel       matlab.ui.container.Panel
        EHIEditField_9      matlab.ui.control.NumericEditField
        SRHLabel_8          matlab.ui.control.Label
        SBLIEditField_8     matlab.ui.control.NumericEditField
        SRHLabel_7          matlab.ui.control.Label
        SigSvrEditField_7   matlab.ui.control.NumericEditField
        SRHLabel_6          matlab.ui.control.Label
        SCPEditField_6      matlab.ui.control.NumericEditField
        SRHLabel_5          matlab.ui.control.Label
        CALCULATEButton     matlab.ui.control.Button
        TorRiskGauge        matlab.ui.control.Gauge
        ShearPanel          matlab.ui.container.Panel
        low01EditField_5    matlab.ui.control.NumericEditField
        SRHLabel_4          matlab.ui.control.Label
        mid46EditField_4    matlab.ui.control.NumericEditField
        SRHLabel_3          matlab.ui.control.Label
        midsrhEditField_3   matlab.ui.control.NumericEditField
        SRHLabel_2          matlab.ui.control.Label
        SRHLabel            matlab.ui.control.Label
        BRNEditField        matlab.ui.control.NumericEditField
        BRNEditFieldLabel   matlab.ui.control.Label
        lowsrhEditField_2   matlab.ui.control.NumericEditField
        EBWDEditField       matlab.ui.control.NumericEditField
        EBWDEditFieldLabel  matlab.ui.control.Label
        km06EditField       matlab.ui.control.NumericEditField
        KMEditFieldLabel    matlab.ui.control.Label
        HeightsPanel        matlab.ui.container.Panel
        LFCEditField        matlab.ui.control.NumericEditField
        LFCEditFieldLabel   matlab.ui.control.Label
        LCLEditField        matlab.ui.control.NumericEditField
        LCLEditFieldLabel   matlab.ui.control.Label
        CAPEPanel           matlab.ui.container.Panel
        CINEditField        matlab.ui.control.NumericEditField
        CINEditFieldLabel   matlab.ui.control.Label
        NEditField          matlab.ui.control.NumericEditField
        NLabel              matlab.ui.control.Label
        DEditField          matlab.ui.control.NumericEditField
        DEditFieldLabel     matlab.ui.control.Label
        cape3EditField      matlab.ui.control.NumericEditField
        EditFieldLabel      matlab.ui.control.Label
        MLEditField         matlab.ui.control.NumericEditField
        MLEditFieldLabel    matlab.ui.control.Label
        SBEditField         matlab.ui.control.NumericEditField
        SBEditFieldLabel    matlab.ui.control.Label
        MUEditField         matlab.ui.control.NumericEditField
        MUEditFieldLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            intot = 0;
            % Hey! if you are reading this, you found the code easter egg!

            % If you are here and know what you're doing, dm me on discord
            % (@racc.00n) I am always looking for new people to work with
            % on future projects.
            
            % If you don't know what you are doing, also dm me on discord
            % (@racc.00n) and I am happy to help with any issues or
            % questions you may have.
            if(app.ftmSwitch.Value == "ft")
            if(app.LFCEditField.Value < 6500)
                intot = intot + 1;
            end
            if(app.LCLEditField.Value <= 2600)
                intot = intot + 1;
                if(app.LCLEditField.Value >= 1600)
                intot = intot + 1;
                end
            end
            else
                if(app.LFCEditField.Value < 1981.2)
                intot = intot + 1;
                end
                if(app.LCLEditField.Value <= 792.48)
                intot = intot + 1;
                    if(app.LCLEditField.Value >= 487.68)
                intot = intot + 1;
                    end
                end
            end
            if(app.MUEditField.Value >= 3000)
                intot = intot + 4;
            elseif(app.MUEditField.Value >= 2500)
                intot = intot + 3;
            elseif(app.MUEditField.Value >= 1000)
                intot = intot + 2;
            elseif(app.MUEditField.Value >= 300)
                intot = intot + 1;
            end
            if(app.SBLIEditField_8.Value < -9)
                intot = intot + 5;
            elseif(app.SBLIEditField_8.Value < -7)
                intot = intot + 4;
            elseif(app.SBLIEditField_8.Value < -5)
                intot = intot + 3;
            elseif(app.SBLIEditField_8.Value < -3)
                intot = intot + 2;
            elseif(app.SBLIEditField_8.Value < 0)
                intot = intot + 1;
            end
            if(app.SigSvrEditField_7.Value > 20000)
                intot = intot + 1;
            end
            if(app.EHIEditField_9.Value >= 2 && app.EHIEditField_9 <=2.4)
                intot = intot + 1;
            elseif(app.EHIEditField_9.Value >= 2.5 && app.EHIEditField_9 <= 2.9)
                intot = intot + 2;
            elseif(app.EHIEditField_9.Value >= 3.0 && app.EHIEditField_9 <= 3.9)
                intot = intot + 3;
            elseif(app.EHIEditField_9.Value >= 4)
                intot = intot + 4;
            elseif(app.EHIEditField_9.Value < 0)
                intot = intot - 1;
            end
            if(app.BRNEditField.Value >= 25 && app.BRNEditField.Value <= 100)
                intot = intot + 1;
            end
            if(app.SCPEditField_6.Value >= 2 && app.SCPEditField_6.Value <= 11)
                intot = intot + 1;
            elseif(app.SCPEditField_6.Value > 11)
                intot = intot + 2;
            end
            % maybe remove this parameter and replace w/ something else
            if(app.mid46EditField_4.Value > 15)
                intot = intot + 1;
            end
            if(app.lowsrhEditField_2.Value >= 100)
                intot = intot + 1;
            end
            if(app.midsrhEditField_3.Value >= 250)
                intot = intot + 1;
            end
            if(app.EBWDEditField.Value >= 25 && app.EBWDEditField.Value <= 45)
                intot = intot + 1;
            elseif(app.EBWDEditField.Value > 45)
                intot = intot + 2;
            end
            if(app.km06EditField.Value >= 35 && app.km06EditField.Value <= 40)
                intot = intot + 1;
            elseif(app.km06EditField.Value > 40)
                intot = intot + 2;
            end
            if(app.low01EditField_5.Value >= 15 && app.low01EditField_5.Value <= 20)
                intot = intot + 1;
            elseif(app.low01EditField_5.Value > 20)
                intot = intot + 2;
            end
            if(app.CINEditField.Value < 0 && app.CINEditField.Value >= -20)
                intot = intot - 1;
            elseif(app.CINEditField.Value < -20 && app.CINEditField.Value >= -60)
                intot = intot - 2;
            elseif(app.CINEditField.Value < -60 && app.CINEditField.Value >= -100)
                intot = intot - 3;
            elseif(app.CINEditField.Value < -100)
                intot = intot - 4;
            end
            
            app.TorRiskGauge.Value = intot;
            app.ValueEditField.Value = string(intot);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CAPEPanel = uipanel(app.UIFigure);
            app.CAPEPanel.Title = 'CAPE';
            app.MUEditFieldLabel = uilabel(app.CAPEPanel);
            app.MUEditFieldLabel.Text = 'MU';
            app.MUEditField = uieditfield(app.CAPEPanel, 'numeric');
            app.SBEditFieldLabel = uilabel(app.CAPEPanel);
            app.SBEditFieldLabel.Text = 'SB';
            app.SBEditField = uieditfield(app.CAPEPanel, 'numeric');
            app.MLEditFieldLabel = uilabel(app.CAPEPanel);
            app.MLEditFieldLabel.Text = 'ML';
            app.MLEditField = uieditfield(app.CAPEPanel, 'numeric');
            app.EditFieldLabel = uilabel(app.CAPEPanel);
            app.EditFieldLabel.Text = '3';
            app.cape3EditField = uieditfield(app.CAPEPanel, 'numeric');
            app.DEditFieldLabel = uilabel(app.CAPEPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.CAPEPanel, 'numeric');
            app.NLabel = uilabel(app.CAPEPanel);
            app.NLabel.Text = 'N';
            app.NEditField = uieditfield(app.CAPEPanel, 'numeric');
            app.CINEditFieldLabel = uilabel(app.CAPEPanel);
            app.CINEditFieldLabel.Text = 'CIN';
            app.CINEditField = uieditfield(app.CAPEPanel, 'numeric');
            app.HeightsPanel = uipanel(app.UIFigure);
            app.HeightsPanel.Title = 'Heights';
            app.LCLEditFieldLabel = uilabel(app.HeightsPanel);
            app.LCLEditFieldLabel.Text = 'LCL';
            app.LCLEditField = uieditfield(app.HeightsPanel, 'numeric');
            app.LFCEditFieldLabel = uilabel(app.HeightsPanel);
            app.LFCEditFieldLabel.Text = 'LFC ';
            app.LFCEditField = uieditfield(app.HeightsPanel, 'numeric');
            app.ShearPanel = uipanel(app.UIFigure);
            app.ShearPanel.Title = 'Shear';
            app.KMEditFieldLabel = uilabel(app.ShearPanel);
            app.KMEditFieldLabel.Text = '0-6 KM';
            app.km06EditField = uieditfield(app.ShearPanel, 'numeric');
            app.EBWDEditFieldLabel = uilabel(app.ShearPanel);
            app.EBWDEditFieldLabel.Text = 'EBWD';
            app.EBWDEditField = uieditfield(app.ShearPanel, 'numeric');
            app.lowsrhEditField_2 = uieditfield(app.ShearPanel, 'numeric');
            app.BRNEditFieldLabel = uilabel(app.ShearPanel);
            app.BRNEditFieldLabel.Text = 'BRN';
            app.BRNEditField = uieditfield(app.ShearPanel, 'numeric');
            app.SRHLabel = uilabel(app.ShearPanel);
            app.SRHLabel.Text = '0-1 SRH';
            app.SRHLabel_2 = uilabel(app.ShearPanel);
            app.SRHLabel_2.Text = '0-3 SRH';
            app.midsrhEditField_3 = uieditfield(app.ShearPanel, 'numeric');
            app.SRHLabel_3 = uilabel(app.ShearPanel);
            app.SRHLabel_3.Text = '4-6 KM';
            app.mid46EditField_4 = uieditfield(app.ShearPanel, 'numeric');
            app.SRHLabel_4 = uilabel(app.ShearPanel);
            app.SRHLabel_4.Text = '0-1 KM';
            app.low01EditField_5 = uieditfield(app.ShearPanel, 'numeric');
            app.TorRiskGauge = uigauge(app.UIFigure, 'circular');
            app.TorRiskGauge.Limits = [0 30];
            app.TorRiskGauge.MajorTicks = [3 9 15 21 27];
            app.TorRiskGauge.MajorTickLabels = {'MGL', 'SLGT', 'ENH', 'MDT', 'HIGH'};
            app.TorRiskGauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.CALCULATEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.IndiciesPanel = uipanel(app.UIFigure);
            app.IndiciesPanel.Title = 'Indicies';
            app.SRHLabel_5 = uilabel(app.IndiciesPanel);
            app.SRHLabel_5.Text = 'SCP';
            app.SCPEditField_6 = uieditfield(app.IndiciesPanel, 'numeric');
            app.SRHLabel_6 = uilabel(app.IndiciesPanel);
            app.SRHLabel_6.Text = 'SigSvr';
            app.SigSvrEditField_7 = uieditfield(app.IndiciesPanel, 'numeric');
            app.SRHLabel_7 = uilabel(app.IndiciesPanel);
            app.SRHLabel_7.Text = 'SB LI';
            app.SBLIEditField_8 = uieditfield(app.IndiciesPanel, 'numeric');
            app.SRHLabel_8 = uilabel(app.IndiciesPanel);
            app.SRHLabel_8.Text = 'EHI';
            app.EHIEditField_9 = uieditfield(app.IndiciesPanel, 'numeric');
            app.TRESLabel = uilabel(app.UIFigure);
            app.TRESLabel.Text = 'T.R.E.S.';
            app.ValueEditField = uieditfield(app.UIFigure, 'text');
            app.ftmSwitch = uiswitch(app.UIFigure, 'slider');
            app.ftmSwitch.Items = {'ft', 'm'};
            app.ftmSwitch.Value = 'ft';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TRES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end